classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        DutyCycleEditField       matlab.ui.control.NumericEditField
        DutyCycleEditFieldLabel  matlab.ui.control.Label
        STARTButton              matlab.ui.control.Button
        UIAxes_3                 matlab.ui.control.UIAxes
        UIAxes_2                 matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            app.STARTButton.Visible = false;
            arduino = serialport("/dev/ttyACM0", 9600, "Timeout",15);
            pause(1);
            adc_count = zeros(1,101);
            DC = zeros(1, 101);
            V_res = zeros(1, 101);
            V_pc = zeros(1, 101);
            I = zeros(1, 101);
            R = zeros(1, 101);
            for K = 0 : 100
                i = K + 1;
                flush(arduino)
                write(arduino,K,'string');
                pause(0.5)
                a=readline(arduino);
                flush(arduino)
                adc_count(i)=str2double(a);
                DC(i) = K;
                V_res(i) = adc_count(i) * 5 / 1023;
                V_pc(i) = 5 - V_res(i);
                I(i) = 1000 * V_res(i) / 10000;
                R(i) = V_pc(i) / I(i);
                app.DutyCycleEditField.Value = K;
                plot(app.UIAxes, DC(1:i), V_pc(1:i), "bo", MarkerFaceColor='b');
                plot(app.UIAxes_2, DC(1:i), I(1:i), "bo", MarkerFaceColor='b');
                plot(app.UIAxes_3, DC(1:i), R(1:i), "bo", MarkerFaceColor='b');
            end
            delete(arduino);
            clear arduino;
            app.STARTButton.Visible = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Photocell Voltage')
            xlabel(app.UIAxes, 'Duty Cycle (%)')
            ylabel(app.UIAxes, 'Voltage (V)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 100];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Photocell Current')
            xlabel(app.UIAxes_2, 'Duty Cycle (%)')
            ylabel(app.UIAxes_2, 'Current (mA)')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XLim = [0 100];
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Photocell Resistance')
            xlabel(app.UIAxes_3, 'Duty Cycle (%)')
            ylabel(app.UIAxes_3, 'Resistance (KOhm)')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_3.XLim = [0 100];
            app.UIAxes_3.YLim = [0 100];
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.DutyCycleEditFieldLabel = uilabel(app.UIFigure);
            app.DutyCycleEditFieldLabel.Text = 'Duty Cycle (%)';
            app.DutyCycleEditField = uieditfield(app.UIFigure, 'numeric');
            app.DutyCycleEditField.Limits = [0 100];
            app.DutyCycleEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end